classdef gui1_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        axes2        matlab.ui.control.UIAxes
        pushbutton2  matlab.ui.control.Button
        pushbutton3  matlab.ui.control.Button
        pushbutton4  matlab.ui.control.Button
        slider1      matlab.ui.control.Slider
        text2        matlab.ui.control.Label
        pushbutton7  matlab.ui.control.Button
    end

    
    methods (Access = private)
        function pushbutton1_Callback(app, hObject, eventdata, handles)
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            AxesBg = axes('units','pixels','pos',[0,0,1000,1000]);
            uistack(AxesBg,'down');
            axes(AxesBg);
            %axes(app.axes1);
            image = imread('screen.png');
            
            imshow(image);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function gui1_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to gui1 (see VARARGIN)
%             background = importdata('scene.jpg');
%             axes(app.axes2);
%             image(background);
%             axis off;
%             global img_name;
%             disp("fun");
%             disp(img_name);
%             % Choose default command line output for gui1
%             app.img = varargin{1};
%             app.img_name = img_name;
%             app.scale = 1;
%             app.window = 800;
%             app.window_temp = 800;
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
        end

        % Button pushed function: pushbutton2
        function pushbutton2_Callback(app, event)
            % hObject    handle to pushbutton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            window_greater = app.window_temp;
            window_greater = window_greater * 1.2;
            img = app.img;
            resize_imtool1(img, window_greater,app.img_name);
            app.window_temp = window_greater;
            % [m,n,l] = size(img);
            % app.window = window_greater;
            % if m >= n
            %     m1 = window_greater;
            %     n1 = n/m * window_greater;
            % else
            %     n1 = window_greater;
            %     m1 = m/n * window_greater;
            % end
            % img1 = imresize(img,[m1 n1]);
            % imtool(img1);
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton3
        function pushbutton3_Callback(app, event)
            window_less = app.window_temp;
            window_less = window_less * 0.8;
            img = app.img;
            resize_imtool1(img, window_less,app.img_name);
            app.window_temp = window_less;
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton4
        function pushbutton4_Callback(app, event)
            % hObject    handle to pushbutton4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            window = app.window;  %660
            % = imread('points.jpg');
            app.window_temp = app.window;
            img = app.img;
            [m,n,l] = size(img);
            if m >= n
                m1 = window;
                n1 = n/m * window;
                scale = window/m;
            else
                n1 = window;
                m1 = m/n * window;
            end
            scale_per = round(scale *100);
            %img_name = inputname(1);
            %img_name = app.img_name;
            disp('显示名称');
            %global img_name;
            %disp(img_name);
            img_name_gui = app.img_name;
            display = sprintf("%s %d%s",img_name_gui,scale_per,'%');
            img1 = imresize(img,[m1 n1]);
            hfigure = imtool(img1);
            %img_name1 = get(hfigure,'name');
            %str =  img_name + scale_per + "%";
            set(hfigure,'name',display,'NumberTitle','on');
        end

        % Button pushed function: pushbutton7
        function pushbutton7_Callback(app, event)
            % hObject    handle to pushbutton7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            imtool(app.img);
        end

        % Value changed function: slider1
        function slider1_Callback(app, event)
            % hObject    handle to slider1 (see GCBO)
            val = get(hObject, 'value');
            disp(val);
            window_val = app.window_temp;
            window_val = window_val * (1 + val);
            img = app.img;
            resize_imtool1(img, window_val,app.img_name);
            %app.window_temp = window_val;
            guidata(hObject, handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'gui1';
            app.axes2 = uiaxes(app.figure1);
            app.pushbutton2 = uibutton(app.figure1, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2_Callback, true);
            app.pushbutton2.Text = '放大';
            app.pushbutton3 = uibutton(app.figure1, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_Callback, true);
            app.pushbutton3.Text = '缩小';
            app.pushbutton4 = uibutton(app.figure1, 'push');
            app.pushbutton4.ButtonPushedFcn = createCallbackFcn(app, @pushbutton4_Callback, true);
            app.pushbutton4.Text = '可视化图片';
            app.slider1 = uislider(app.figure1);
            app.slider1.Limits = [0 1];
            app.slider1.MajorTicks = [];
            app.slider1.ValueChangedFcn = createCallbackFcn(app, @slider1_Callback, true);
            app.slider1.MinorTicks = [];
            app.text2 = uilabel(app.figure1);
            app.text2.Text = '微调窗口大小';
            app.pushbutton7 = uibutton(app.figure1, 'push');
            app.pushbutton7.ButtonPushedFcn = createCallbackFcn(app, @pushbutton7_Callback, true);
            app.pushbutton7.Text = '原始图片';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui1_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @gui1_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end